classdef Dev_ctrl < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        cameraTab                      matlab.ui.container.Tab
        TabGroup2                      matlab.ui.container.TabGroup
        liveTab                        matlab.ui.container.Tab
        reflineCheckBox                matlab.ui.control.CheckBox
        holdCheckBox                   matlab.ui.control.CheckBox
        gainEditField                  matlab.ui.control.NumericEditField
        gainEditFieldLabel             matlab.ui.control.Label
        cameraButtonGroup              matlab.ui.container.ButtonGroup
        BSIExpressButton               matlab.ui.control.RadioButton
        Infinity2Button                matlab.ui.control.RadioButton
        lefttopwidthheightLabel        matlab.ui.control.Label
        ROIselectCheckBox              matlab.ui.control.CheckBox
        closeButton                    matlab.ui.control.Button
        HaltButton                     matlab.ui.control.Button
        autocontrastCheckBox           matlab.ui.control.CheckBox
        maxSlider                      matlab.ui.control.Slider
        maxSliderLabel                 matlab.ui.control.Label
        minSlider                      matlab.ui.control.Slider
        minSliderLabel                 matlab.ui.control.Label
        live_start_                    matlab.ui.control.Button
        ROIDropDown                    matlab.ui.control.DropDown
        ROIDropDownLabel               matlab.ui.control.Label
        binsizeDropDown                matlab.ui.control.DropDown
        binsizeDropDownLabel           matlab.ui.control.Label
        exposuretimemsEditField        matlab.ui.control.NumericEditField
        exposuretimemsEditFieldLabel   matlab.ui.control.Label
        UIAxes2                        matlab.ui.control.UIAxes
        WFaqTab                        matlab.ui.container.Tab
        snapshotButton                 matlab.ui.control.Button
        updateButton                   matlab.ui.control.Button
        maxSlider_2                    matlab.ui.control.Slider
        maxSlider_2Label               matlab.ui.control.Label
        minSlider_2                    matlab.ui.control.Slider
        minSlider_2Label               matlab.ui.control.Label
        exposuremodeButtonGroup        matlab.ui.container.ButtonGroup
        syncwithpatchclampButton       matlab.ui.control.RadioButton
        freerecordingButton_2          matlab.ui.control.RadioButton
        FilenameEditField              matlab.ui.control.EditField
        FilenameEditFieldLabel         matlab.ui.control.Label
        abortButton                    matlab.ui.control.Button
        DatasavingdirEditField         matlab.ui.control.EditField
        DatasavingdirEditFieldLabel    matlab.ui.control.Label
        recordButton                   matlab.ui.control.Button
        NumberofframesEditField        matlab.ui.control.NumericEditField
        NumberofframesEditFieldLabel   matlab.ui.control.Label
        StreamdurationsEditField       matlab.ui.control.NumericEditField
        StreamdurationsEditFieldLabel  matlab.ui.control.Label
        SMaqTab                        matlab.ui.container.Tab
        patchclampTab                  matlab.ui.container.Tab
        TabGroup3                      matlab.ui.container.TabGroup
        prtcTab                        matlab.ui.container.Tab
        SamplingratekHzEditField       matlab.ui.control.NumericEditField
        SamplingratekHzEditFieldLabel  matlab.ui.control.Label
        deployButton_2                 matlab.ui.control.Button
        stimuluschannelsListBox        matlab.ui.control.ListBox
        editButton                     matlab.ui.control.Button
        removeButton                   matlab.ui.control.Button
        addButton                      matlab.ui.control.Button
        selectingstimulusDropDown_2Label  matlab.ui.control.Label
        selectingstimulusDropDown_2    matlab.ui.control.DropDown
        stimuluschannelsListBoxLabel   matlab.ui.control.Label
        stimuli_vis                    matlab.ui.control.UIAxes
        caliTab                        matlab.ui.container.Tab
        CpFEditField_2                 matlab.ui.control.NumericEditField
        CpFEditField_2Label            matlab.ui.control.Label
        RMEditField_4                  matlab.ui.control.NumericEditField
        RMEditField_4Label             matlab.ui.control.Label
        RMEditField_3                  matlab.ui.control.NumericEditField
        RMEditField_3Label             matlab.ui.control.Label
        GainPanel                      matlab.ui.container.Panel
        CCcmdpAVEditField              matlab.ui.control.NumericEditField
        CCcmdpAVEditFieldLabel         matlab.ui.control.Label
        VCcmdmVVEditField              matlab.ui.control.NumericEditField
        VCcmdmVVEditFieldLabel         matlab.ui.control.Label
        CCEditField                    matlab.ui.control.EditField
        CCLabel                        matlab.ui.control.Label
        VCEditField                    matlab.ui.control.EditField
        VCEditFieldLabel               matlab.ui.control.Label
        wholecellCheckBox              matlab.ui.control.CheckBox
        gigasealButton                 matlab.ui.control.Button
        liveButton                     matlab.ui.control.Button
        channelButtonGroup             matlab.ui.container.ButtonGroup
        ch2Button                      matlab.ui.control.RadioButton
        ch1Button                      matlab.ui.control.RadioButton
        zeropipetteoffsetButton        matlab.ui.control.Button
        motionTab                      matlab.ui.container.Tab
        TabGroup4                      matlab.ui.container.TabGroup
        xystagesTab                    matlab.ui.container.Tab
        followButton                   matlab.ui.control.Button
        EditField_2                    matlab.ui.control.NumericEditField
        EditField                      matlab.ui.control.NumericEditField
        stepsizemLabel                 matlab.ui.control.Label
        positionmLabel                 matlab.ui.control.Label
        ySpinner                       matlab.ui.control.Spinner
        ySpinnerLabel                  matlab.ui.control.Label
        xSpinner                       matlab.ui.control.Spinner
        xSpinnerLabel                  matlab.ui.control.Label
        selectposDropDown              matlab.ui.control.DropDown
        selectposDropDownLabel         matlab.ui.control.Label
        deleteselectedposButton        matlab.ui.control.Button
        gotohomeButton                 matlab.ui.control.Button
        sethomeButton                  matlab.ui.control.Button
        gotoselectedposButton          matlab.ui.control.Button
        registercurrentposButton       matlab.ui.control.Button
        UIAxes4                        matlab.ui.control.UIAxes
        zstageTab                      matlab.ui.container.Tab
        manipulatorsTab                matlab.ui.container.Tab
        manipulator1Label              matlab.ui.control.Label
        workButton                     matlab.ui.control.Button
        homeButton                     matlab.ui.control.Button
        setworkposButton               matlab.ui.control.Button
        excitationTab                  matlab.ui.container.Tab
        powerSpinner_2                 matlab.ui.control.Spinner
        powerSpinner_2Label            matlab.ui.control.Label
        powerSpinner                   matlab.ui.control.Spinner
        powerSpinnerLabel              matlab.ui.control.Label
        Slider2                        matlab.ui.control.Slider
        Slider                         matlab.ui.control.Slider
        Lamp2                          matlab.ui.control.Lamp
        nmlaserCheckBox                matlab.ui.control.CheckBox
        LEDCheckBox                    matlab.ui.control.CheckBox
        Lamp                           matlab.ui.control.Lamp
    end

    properties (Access = public)
        camera_BSI = struct('PmDeviceFormat', [], 'PmImaqInfo', [], 'PMDeviceID', [], 'obj', [], 'src', [], 'ImgAx', [], 'live_handle',[]); %
        camera_Infinity = struct('obj', [], 'src', [],'adaptor',[]);
        NI_daq = struct('daq_list',[],'daq_cam',[],'daq_illum_switch',[],'daq_illum_inten',[],'daq_flip_M',[]);
        MC_dev = struct('handle',[],'id',[], 'livemode',false);
        stimu_path = 'D:\Code\Hao\2matlab\Device_control\stimuli';
        queued_sequence = [];
        mem_confirm_dialog; %
        DAQ_vis_graph = struct('ch1',[],'ch2',[]);
        acquisition_log = struct('Camera',[],'PatchClamp',[]);
        obj_client;
        para_obj;
        multi_clamp_para = struct('ch1',struct('GigaSealR',0,'WholeCellR',0,'WholeCellC',0,'VC_R','500M','CC_R','500M','VC_cmd',20,'CC_cmd',400),...
            'ch2',struct('GigaSealR',0,'WholeCellR',0,'WholeCellC',0,'VC_R','500M','CC_R','500M','VC_cmd',20,'CC_cmd',400));
    end
    properties (Access = private)

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % initialize server
            app.obj_client = tcpclient("172.29.28.198",4000);
            configureTerminator(app.obj_client,"LF");
            %             configureCallback(app.obj_client,"terminator",@(src,event)Fcn_read_server(src,event));
            app.obj_client.UserData = readline(app.obj_client);
            if isempty(app.obj_client.UserData)
                disp('Cannot connect to server.')
            else
                disp(app.obj_client.UserData);
            end
            writeline(app.obj_client,'xy_POS');
            app.obj_client.UserData = readline(app.obj_client);
            xy_pos = regexp(app.obj_client.UserData,'\t','split');
            x_pos = -round(str2num(xy_pos(1))/10);
            y_pos = round(str2num(xy_pos(2))/10);
            disp(strcat("xy stage: x position is"," ",num2str(x_pos)," ","µm and y position is"," ",num2str(y_pos)," ","µm"));
            app.xSpinner.Value = x_pos;
            app.ySpinner.Value = y_pos;
            writeline(app.obj_client,'manip1_POS');
            app.obj_client.UserData = readline(app.obj_client);
            xyz_pos = regexp(app.obj_client.UserData,'\t','split');
            x_pos = num2str(round(str2num(xyz_pos(1))/10));
            y_pos = num2str(round(str2num(xyz_pos(2))/10));
            z_pos = num2str(round(str2num(xyz_pos(3))/10));
            disp(strcat("manipulator1: x position is"," ",x_pos," ","µm, y position is"," ",y_pos," ","µm, z_pos is"," ",z_pos," ","µm"));
            writeline(app.obj_client,'manip2_POS');
            app.obj_client.UserData = readline(app.obj_client);
            xyz_pos = regexp(app.obj_client.UserData,'\t','split');
            x_pos = num2str(round(str2num(xyz_pos(1))/10));
            y_pos = num2str(round(str2num(xyz_pos(2))/10));
            z_pos = num2str(round(str2num(xyz_pos(3))/10));
            disp(strcat("manipulator2: x position is"," ",x_pos," ","µm, y position is"," ",y_pos," ","µm, z_pos is"," ",z_pos," ","µm"));

            % initialize xy stage figure
            theta = linspace(0,2*pi,1000);
            rho0 = 9;
            rho1 = 8;
            rho2 = 6;
            rho3 = 3;
            x0 = cos(theta)*rho0;
            y0 = sin(theta)*rho0;
            x1 = cos(theta)*rho1;
            y1 = sin(theta)*rho1;
            x2 = cos(theta)*rho2;
            y2 = sin(theta)*rho2;
            x3 = cos(theta)*rho3;
            y3 = sin(theta)*rho3;
            hold(app.UIAxes4, "on");
            patch(app.UIAxes4,x0,y0,[0.5,0,0]);
            patch(app.UIAxes4,x1,y1,[1,0.7,0.2]);
            patch(app.UIAxes4,x2,y2,[0.8,0.9,0.2]);
            patch(app.UIAxes4,x3,y3,[0.1,1,0.3]);
            Fcn_plot_cur_pos(app);

            % initial live position updating
            app.para_obj.POS_timer = timer('ExecutionMode','fixedRate','Period',0.5,'StartDelay',0.1);
            app.para_obj.POS_timer.TimerFcn = @(~,~) PFcn_live_pos_update(app);
            start(app.para_obj.POS_timer);

            % initialize stimulus file dir
            files_list = dir(fullfile(app.stimu_path,'*.m'));
            for i = 1:length(files_list)
                cur_name = files_list(i);
                cur_name = cur_name.name;
                cur_name = erase(cur_name,'.m');
                app.selectingstimulusDropDown_2.Items = [app.selectingstimulusDropDown_2.Items cur_name];
            end

            % initialize laser and LED switches and intensity
            app.NI_daq.daq_illum_switch = daq("ni");
            app.NI_daq.daq_illum_inten = daq("ni");
            addoutput(app.NI_daq.daq_illum_inten,"Dev2","ao1","Voltage");
            addoutput(app.NI_daq.daq_illum_inten,"Dev2","ao0","Voltage");
            addoutput(app.NI_daq.daq_illum_switch,"Dev2","port0/line1","Digital");
            addoutput(app.NI_daq.daq_illum_switch,"Dev2","port0/line0","Digital");
            write(app.NI_daq.daq_illum_switch,[0 0]);
            write(app.NI_daq.daq_illum_inten,[0 0]);

            % initialize flip mirror
            app.NI_daq.daq_flip_M = daq("ni");
            addoutput(app.NI_daq.daq_flip_M,"Dev2","port0/line2","Digital");
            write(app.NI_daq.daq_flip_M,0);

            % initialize multiclamp
            try
                [app.MC_dev.handle app.MC_dev.id] = MC_init;
                app.channelButtonGroup.Visible = "on";
                if length(app.MC_dev.id)>1
                    app.ch2Button.Visible = "on";
                end
            catch
                disp('No patchclamp device found.')
            end

            % initialize cameras
            % Lucam Infinity 2
            info = imaqhwinfo;
            app.camera_Infinity.adaptor = imaqhwinfo(info.InstalledAdaptors{1,1});
            app.camera_Infinity.obj = videoinput(app.camera_Infinity.adaptor.AdaptorName,1,app.camera_Infinity.adaptor.DeviceInfo.SupportedFormats{1,16}); % full frame 1616x1216 pixels 2x2 binning
            app.camera_Infinity.src = getselectedsource(app.camera_Infinity.obj);
            app.camera_Infinity.obj.FramesPerTrigger = Inf;
            app.camera_Infinity.obj.ROIPosition = [148 48 512 512]; % set ROI (1024x1024) pixel size 4.4 um

            % BSI Express
            %Detect what PM devices are availaible
            app.camera_BSI.PmImaqInfo = GetPMAdaptorInfo();
            %Get PM Device to run
            [PMDeviceInfo,app.camera_BSI.PMDeviceID, ~] = SelectPMDevice(app.camera_BSI.PmImaqInfo);
            %Get Device format to open
            [app.camera_BSI.PmDeviceFormat, ~] = SelectDeviceFormat(PMDeviceInfo);
            % HDR 16 bit dynamic range
            % CMS 12 bit dynamic range with low noise level as low as 1e-
            % other modes with 11 bit provide highest frame rate
            %Open device in selected device format
            app.camera_BSI.obj = videoinput(app.camera_BSI.PmImaqInfo.AdaptorName, app.camera_BSI.PMDeviceID, app.camera_BSI.PmDeviceFormat);
            app.camera_BSI.src = getselectedsource(app.camera_BSI.obj);
            %Set Frames per trigger to Inf
            app.camera_BSI.obj.FramesPerTrigger = Inf;
            % where off the camera preview feature converts the raw
            % format to 8 bit image internally, where on preview
            % feature outputs the 16bit regardless of the selection in
            % SelectDeviceFormat function
            app.camera_BSI.obj.PreviewFullBitDepth = 'on';
            UIcomp = struct('min_slider',app.minSlider,'max_slider',app.maxSlider,'hHist',app.UIAxes2,'auto_cont',app.autocontrastCheckBox,'hold',app.holdCheckBox);
            app.camera_BSI.obj.UserData = UIcomp;
            app.camera_Infinity.obj.UserData = UIcomp;
            app.NI_daq.daq_cam = daq("ni");
            app.deployButton_2.UserData = struct('memConfirm_wait',[],'memConfirm_sele',[],'ch',[]);
        end

        % Button pushed function: live_start_
        function live_start_Pushed(app, event)
            %%%%%%%%%% live camera parameter setting %%%%%%%%
            switch app.cameraButtonGroup.SelectedObject.Text
                case 'Infinity 2'
                    % set exposure time
                    app.camera_Infinity.src.EXPOSURE = app.exposuretimemsEditField.Value;
                    % set timer to lively update contrast
                    app.camera_Infinity.src.GAIN = app.gainEditField.Value;
                    if isempty(findobj('Tag','live_stream'))
                        hFig = figure('Toolbar','none',...
                            'Menubar', 'none',...
                            'NumberTitle','Off',...
                            'Name','live stream','Tag','live_stream','CloseRequestFcn',@closeRequest);
                    else
                        hFig = findobj('Tag','live_stream');
                    end
                    img_W = 512;
                    img_H = 512;
                    screenSize = get(0,'ScreenSize');
                    screenWidthToHeight = screenSize(3)/screenSize(4);
                    imageWidthToHeight = img_W/img_H;
                    if imageWidthToHeight <= 1
                        set(hFig, 'Units', 'Normalized', 'OuterPosition', [0.58 0.15 0.75/screenWidthToHeight*0.972*imageWidthToHeight 0.75]);
                    else
                        set(hFig, 'Units', 'Normalized', 'OuterPosition', [0.45 0.15+(0.75-0.15)*(1-1/imageWidthToHeight) 0.75/screenWidthToHeight*0.972 0.75/imageWidthToHeight*1.14]);
                    end
                    %%%%%%%%%%%%%%%%%%%%%%%
                    % display the video preview data.
                    app.camera_BSI.ImgAx = gca;
                    app.camera_BSI.ImgAx.InnerPosition = [0 0 1 1];
                    app.camera_BSI.live_handle = image(app.camera_BSI.ImgAx, zeros(img_H, img_W));
                    axis equal;
                    %Start previewing
                    setappdata(app.camera_BSI.live_handle,'UpdatePreviewWindowFcn',@Fcn_preview_update);
                    preview(app.camera_Infinity.obj,app.camera_BSI.live_handle);
                    if app.reflineCheckBox.Value
                        x = linspace(1,512,512);
                        y = ones(size(x))*256;
                        hold(app.camera_BSI.ImgAx,"on");
                        h1 = plot(app.camera_BSI.ImgAx,x,y,'LineWidth',1,'LineStyle','--','Color',"r");
                        h2 = plot(app.camera_BSI.ImgAx,y,x,'LineWidth',1,'LineStyle','--','Color',"r");
                        app.camera_Infinity.refline1 = h1;
                        app.camera_Infinity.refline2 = h2;
                        hold(app.camera_BSI.ImgAx,"off");
                    end
                case 'BSI Express'
                    app.camera_BSI.obj.FramesPerTrigger = Inf;
                    triggerconfig(app.camera_BSI.obj,'immediate','none','none');
                    app.camera_BSI.src.ExposeOutMode = 'Rolling Shutter';  % defines Trigger Ready position and Expose out signal
                    app.camera_BSI.src.TriggerMode = 'Internal Trigger';
                    app.camera_BSI.src.AutoContrast = 'OFF'; %ON-AUTO
                    binsize = 1;
                    switch app.binsizeDropDown.Value
                        case '1 × 1'
                            app.camera_BSI.src.Binning = '1x1';
                        case '2 × 2'
                            binsize = 2;
                            app.camera_BSI.src.Binning = '2x2';
                    end
                    app.camera_BSI.src.Exposure = app.exposuretimemsEditField.Value;
                    %Get image size
                    comma_pos = strfind(app.ROIDropDown.Value,',');
                    strL = strlength(app.ROIDropDown.Value);
                    img_W = str2double(app.ROIDropDown.Value(comma_pos(2)+1:comma_pos(3)-1));
                    img_H = str2double(app.ROIDropDown.Value(comma_pos(3)+1:strL));
                    x_offset = str2double(app.ROIDropDown.Value(1:comma_pos(1)-1))-1;
                    y_offset = str2double(app.ROIDropDown.Value(comma_pos(1)+1:comma_pos(2)-1))-1;
                    app.camera_BSI.obj.ROIPosition = [x_offset y_offset img_W img_H];
                    % Create a figure window. This example turns off the default
                    % toolbar and menubar in the figure.
                    if isempty(findobj('Tag','live_stream'))
                         hFig = figure('Toolbar','none',...
                            'Menubar', 'none',...
                            'NumberTitle','Off',...
                            'Name','live stream','Tag','live_stream','CloseRequestFcn',@closeRequest);
                    else
                        hFig = findobj('Tag','live_stream');
                    end
                    screenSize = get(0,'ScreenSize');
                    screenWidthToHeight = screenSize(3)/screenSize(4);
                    imageWidthToHeight = img_W/img_H;
                    %Lets try to keep the aspect ratio of the image while enlarging the figure
                    if imageWidthToHeight <= 1
                        set(hFig, 'Units', 'Normalized', 'OuterPosition', [0.58 0.15 0.75/screenWidthToHeight*0.972*imageWidthToHeight 0.75]);
                    else
                        set(hFig, 'Units', 'Normalized', 'OuterPosition', [0.58 0.15+(0.75-0.15)*(1-1/imageWidthToHeight) 0.75/screenWidthToHeight*0.972 0.75/imageWidthToHeight*1.14]);
                    end
                    nBands = app.camera_BSI.obj.NumberOfBands;
                    %%%%%%%%%%%%%%%%%%%%%%%
                    % Create the image object in which you want to
                    % display the video preview data.
                    app.camera_BSI.ImgAx = gca;
                    app.camera_BSI.ImgAx.InnerPosition = [0 0 1 1];
                    app.camera_BSI.live_handle = image(app.camera_BSI.ImgAx, zeros(img_H/binsize, img_W/binsize, nBands));
                    axis equal;
                    %Start previewing
                    setappdata(app.camera_BSI.live_handle,'UpdatePreviewWindowFcn',@Fcn_preview_update);
                    preview(app.camera_BSI.obj,app.camera_BSI.live_handle);
                    if app.reflineCheckBox.Value
                        x = linspace(1,img_W,img_W);
                        y = ones(size(x))*img_H/2;
                        hold(app.camera_BSI.ImgAx,"on");
                        h1 = plot(app.camera_BSI.ImgAx,x,y,'LineWidth',1,'LineStyle','--','Color',"r");
                        h2 = plot(app.camera_BSI.ImgAx,y,x,'LineWidth',1,'LineStyle','--','Color',"r");
                        app.camera_BSI.refline1 = h1;
                        app.camera_BSI.refline2 = h2;
                        hold(app.camera_BSI.ImgAx,"off");
                    end
            end
            app.live_start_.Enable = "off";
            app.HaltButton.Enable = "on";
            app.closeButton.Enable = "off";
        end

        % Button pushed function: HaltButton
        function HaltButtonPushed(app, event)
            switch app.cameraButtonGroup.SelectedObject.Text
                case 'Infinity 2'
                    stoppreview(app.camera_Infinity.obj);
                case 'BSI Express'
                    stoppreview(app.camera_BSI.obj);
            end
            
            app.HaltButton.Enable = "off";
            app.live_start_.Enable = "on";
            app.closeButton.Enable = "on";
        end

        % Button pushed function: closeButton
        function closeButtonPushed(app, event)
            %             stoppreview(app.camera_live.obj);
            hFig = findobj('Tag','live_stream');
            delete(hFig);
            app.closeButton.Enable = "off";
        end

        % Value changed function: ROIselectCheckBox
        function ROIselectCheckBoxValueChanged(app, event)
            comma_pos = strfind(app.ROIDropDown.Value,',');
            strL = strlength(app.ROIDropDown.Value);
            img_H = str2double(app.ROIDropDown.Value(comma_pos(2)+1:comma_pos(3)-1));
            img_W = str2double(app.ROIDropDown.Value(comma_pos(3)+1:strL));
            bin_flag = strcmp(app.binsizeDropDown.Value,'1 × 1');
            trig_condi = ~isempty(findobj('Tag','live_stream'));
            if (app.ROIselectCheckBox.Value)&&(img_H == 2048)&&(img_W == 2048)&&trig_condi&&bin_flag
                % draw a rectangle and pass the ROI info to app
                roi = drawrectangle(app.camera_BSI.ImgAx);
                position_vec = round(roi.Position);
                position_vec = round((position_vec-1)/4)*4+1;
                position_vec(3:4) = position_vec(3:4)-1;
                roi.Position = position_vec;

                % fine adjusting rectangle
                ROI_sele_Box=uifigure('Name','ROI selection','MenuBar','none','ToolBar','none','Tag','ROI_sele');
                initPosition = ROI_sele_Box.Position;  % initPosition(1) initPosition(2) are x y cordinate, whose origin is located on lower left corner of screen
                ROI_sele_Box.Position = [initPosition(1), ...
                    initPosition(2) - 350 + initPosition(4), 300, 145];
                roi_top = uispinner(ROI_sele_Box,'Position',[100,110,65,20],'Limits', [1 2048],'Step',4,'Value',position_vec(2),...
                    'ValueChangingFcn',@(spn,event) spinnerChanging_top(event,roi));
                roi_top_T = uilabel(ROI_sele_Box,'Text','top','Position',[50,110,65,20]);
                roi_left = uispinner(ROI_sele_Box,'Position',[100,80,65,20],'Limits', [1 2048],'Step',4,'Value',position_vec(1),...
                    'ValueChangingFcn',@(spn,event) spinnerChanging_left(event,roi));
                roi_left_T = uilabel(ROI_sele_Box,'Text','left','Position',[50,80,65,20]);
                roi_width = uispinner(ROI_sele_Box,'Position',[100,50,65,20],'Limits', [0 2048],'Step',4,'Value',position_vec(3),...
                    'ValueChangingFcn',@(spn,event) spinnerChanging_width(event,roi));
                roi_width_T = uilabel(ROI_sele_Box,'Text','width','Position',[50,50,65,20]);
                roi_height = uispinner(ROI_sele_Box,'Position',[100,20,65,20],'Limits', [0 2048],'Step',4,'Value',position_vec(4),...
                    'ValueChangingFcn',@(spn,event) spinnerChanging_height(event,roi));
                roi_height_T = uilabel(ROI_sele_Box,'Text','height','Position',[50,20,65,20]);
                confirm_bu = uibutton(ROI_sele_Box,'Text','confirm','Position',[200,70,65,20],'ButtonPushedFcn', @(btn,event) addROI(btn,app,roi,ROI_sele_Box));
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            write(app.NI_daq.daq_illum_switch,[0 0]);
            write(app.NI_daq.daq_illum_inten,[0 0]);
            selectedCam = app.cameraButtonGroup.SelectedObject.Text;
            if strcmp(selectedCam,'BSI Express')
                write(app.NI_daq.daq_flip_M,1);
                pause(0.1);
                write(app.NI_daq.daq_flip_M,0);
            end
            delete(app.obj_client);
            stop(app.para_obj.POS_timer);
            try
                delete(app.DAQ_vis_graph.ch1);
            catch
            end
            try
                delete(app.DAQ_vis_graph.ch2);
            catch
            end
            allfigHandles = findobj('Type', 'figure');
            delete(allfigHandles);
            try
                MC_close(app.MC_dev.handle);
            catch
            end
            delete(app)
        end

        % Value changed function: autocontrastCheckBox
        function autocontrastCheckBoxValueChanged(app, event)
            value = app.autocontrastCheckBox.Value;
            if value == 1
                app.maxSlider.Enable = "off";
                app.minSlider.Enable = "off";
                app.holdCheckBox.Value = 0;
            else
                app.maxSlider.Enable = "on";
                app.minSlider.Enable = "on";
            end
        end

        % Value changed function: StreamdurationsEditField
        function StreamdurationsEditFieldValueChanged(app, event)
            value = app.StreamdurationsEditField.Value;
            exposure_time = app.exposuretimemsEditField.Value;
            total_frames = ceil(value*1000/exposure_time);
            app.NumberofframesEditField.Value = total_frames;
            % For Infinity2 camera frame rate is fixed to 11 FPS
            if strcmp(app.cameraButtonGroup.SelectedObject.Text,'Infinity 2')
                if strcmp(app.exposuremodeButtonGroup.SelectedObject.Text,'free recording')
                    app.NumberofframesEditField.Value = value*11;
                end
            end
        end

        % Button pushed function: recordButton
        function recordButtonPushed(app, event)
            app.recordButton.Enable = 'off';
            app.abortButton.Enable = "on";
            switch app.exposuremodeButtonGroup.SelectedObject.Text
                case 'free recording'

                    selectedCam = app.cameraButtonGroup.SelectedObject.Text;
                    switch selectedCam
                        case 'Infinity 2'
                            app.camera_Infinity.obj.FramesPerTrigger = app.NumberofframesEditField.Value;
                            app.camera_Infinity.src.EXPOSURE = app.exposuretimemsEditField.Value;
                            required_frames = app.NumberofframesEditField.Value;
                            start(app.camera_Infinity.obj);
                            pause(0.5);
                            while (app.camera_Infinity.obj.FramesAcquired ~= required_frames) && (app.abortButton.Enable == "on")
                                pause(0.1);
                                temp_img_live_show = peekdata(app.camera_Infinity.obj,1);
                                temp_img_live_show = imrotate(temp_img_live_show,90);
                                temp_img_live_show = flip(temp_img_live_show,2);
                                ROI = app.camera_Infinity.obj.ROIPosition;
                                temp_img_live_show = temp_img_live_show(ROI(1)+1:ROI(1)+ROI(3),ROI(2)+1:ROI(2)+ROI(4));
                                app.camera_BSI.live_handle.CData = Fcn_record_updata(app, temp_img_live_show);
                            end
                            if app.abortButton.Enable == "on"
                                imgStack = getdata(app.camera_Infinity.obj,app.camera_Infinity.obj.FramesAcquired);
                                imgStack = squeeze(imgStack);
                                img_trim = zeros(512,512,app.camera_Infinity.obj.FramesAcquired);
                                for i = 1:app.camera_Infinity.obj.FramesAcquired
                                    temp = imgStack(:,:,i);
                                    temp = imrotate(temp,90);
                                    temp = flip(temp,2);
                                    temp = temp(ROI(1)+1:ROI(1)+ROI(3),ROI(2)+1:ROI(2)+ROI(4));
                                    img_trim(:,:,i) = temp;
                                end
                                img_trim = uint8(img_trim);
                                fullpath = strcat(app.DatasavingdirEditField.Value,app.FilenameEditField.Value);
                                if ~exist(fullpath, 'dir')
                                    mkdir(fullpath);
                                end
                                option=struct('message',false);
                                res = saveastiff(img_trim, strcat(fullpath,'\Infinity widefield video.tiff'),option);
                            end
                            stop(app.camera_Infinity.obj);
                            app.camera_Infinity.obj.FramesPerTrigger = Inf;
                        case 'BSI Express'
                            app.camera_BSI.obj.FramesPerTrigger = app.NumberofframesEditField.Value;
                            app.camera_BSI.src.Exposure = app.exposuretimemsEditField.Value;
                            required_frames = app.NumberofframesEditField.Value;
                            start(app.camera_BSI.obj);
                            pause(0.5);
                            while (app.camera_BSI.obj.FramesAcquired ~= required_frames) && (app.abortButton.Enable == "on")
                                pause(0.1);
                                temp_img_live_show = peekdata(app.camera_BSI.obj,1);
                                app.camera_BSI.live_handle.CData = Fcn_record_updata(app, temp_img_live_show);
                            end
                            if app.abortButton.Enable == "on"
                                imgStack = getdata(app.camera_BSI.obj,app.camera_BSI.obj.FramesAcquired);
                                imgStack = squeeze(imgStack);
                                fullpath = strcat(app.DatasavingdirEditField.Value,app.FilenameEditField.Value);
                                if ~exist(fullpath, 'dir')
                                    mkdir(fullpath);
                                end
                                option=struct('message',false);
                                res = saveastiff(imgStack, strcat(fullpath,'\BSI Express widefield video.tiff'),option);
                            end
                            stop(app.camera_Infinity.obj);
                            app.camera_BSI.obj.FramesPerTrigger = Inf;
                    end
                case 'sync with patch clamp'
                    app.camera_BSI.src.Exposure = app.exposuretimemsEditField.Value-1;
                    if app.NI_daq.daq_cam.NumScansQueued<1 % in case record botton clicked consecutively
                        if ~isempty(app.queued_sequence)
                            preload(app.NI_daq.daq_cam,app.queued_sequence);
                        else
                            msgbox('Please deploy channel')
                            return;
                        end
                    end
                    [queue_length chs] = size(app.queued_sequence);
                    acq_duration = queue_length/app.SamplingratekHzEditField.Value/1000;
                    scan_data.ai0 = [];
                    scan_data.ai1 = [];
                    scan_data.t = [];
                    start(app.camera_BSI.obj);
                    start(app.NI_daq.daq_cam);
                    pause(0.1);
                    while (app.NI_daq.daq_cam.NumScansQueued>0) && (app.abortButton.Enable == "on")
                        pause(0.01);
                        temp_img_live_show = peekdata(app.camera_BSI.obj,1);
                        app.camera_BSI.live_handle.CData = Fcn_record_updata(app, temp_img_live_show);
                        try     % for two channel IO ready to readout scans are packaged in 1000 time points with 10kHz Rate
                            temp_readout = read(app.NI_daq.daq_cam, app.NI_daq.daq_cam.NumScansAvailable);
                            scan_data.t = [scan_data.t; seconds(temp_readout.Time)];
                            temp_t = (seconds(temp_readout.Time));
                            scan_idx = round(temp_t/acq_duration*queue_length);
                            if chs>2
                                temp_ai0 = temp_readout.Dev1_ai0;
                                scan_data.ai0 = [scan_data.ai0; temp_ai0];
                                temp_ai1 = temp_readout.Dev1_ai1;
                                scan_data.ai1 = [scan_data.ai1; temp_ai1];
                                plot(app.DAQ_vis_graph.ch1.UIAxes2,temp_t,temp_ai0,"red");
                                plot(app.DAQ_vis_graph.ch2.UIAxes2,temp_t,temp_ai1,"red");
                                plot(app.DAQ_vis_graph.ch1.UIAxes,temp_t,app.queued_sequence(scan_idx,1),"blue");
                                plot(app.DAQ_vis_graph.ch2.UIAxes,temp_t,app.queued_sequence(scan_idx,2),"blue");
                            elseif app.deployButton_2.UserData.ch == 1
                                temp_ai0 = temp_readout.Dev1_ai0;
                                scan_data.ai0 = [scan_data.ai0; temp_ai0];
                                plot(app.DAQ_vis_graph.ch1.UIAxes2,temp_t,temp_ai0,"red");
                                plot(app.DAQ_vis_graph.ch1.UIAxes,temp_t,app.queued_sequence(scan_idx,1),"blue");
                            elseif app.deployButton_2.UserData.ch == 2
                                temp_ai1 = temp_readout.Dev1_ai1;
                                scan_data.ai1 = [scan_data.ai1; temp_ai1];
                                plot(app.DAQ_vis_graph.ch2.UIAxes2,temp_t,temp_ai1,"red");
                                plot(app.DAQ_vis_graph.ch2.UIAxes,temp_t,app.queued_sequence(scan_idx,1),"blue");
                            end
                        catch
                        end
                    end
                    pause(0.1);
                    [num_sample, ~] = size(app.queued_sequence);
                    required_frames = num_sample/app.SamplingratekHzEditField.Value/app.exposuretimemsEditField.Value;
                    while (app.camera_BSI.obj.FramesAcquired ~= required_frames) && (app.abortButton.Enable == "on")
                        pause(0.01);
                    end
                    if app.abortButton.Enable == "on" % acquisition is not prematurely terminated
                        imgStack = getdata(app.camera_BSI.obj,app.camera_BSI.obj.FramesAcquired);
                        imgStack = squeeze(imgStack);
                        time_stamp = string(datetime);
                        time_stamp = strrep(time_stamp,' ','_');
                        time_stamp = strrep(time_stamp,':','_');
                        option=struct('message',false);
                        fullpath = strcat(app.DatasavingdirEditField.Value,app.FilenameEditField.Value,'_',time_stamp);
                        mkdir(fullpath);
                        res = saveastiff(imgStack, strcat(fullpath,'\ImgStack.tif'),option);
                        ai_res = read(app.NI_daq.daq_cam,"all");
                        scan_data.t = [scan_data.t; seconds(ai_res.Time)];

                        if chs>2
                            scan_data.ai0 = [scan_data.ai0; ai_res.Dev1_ai0];
                            scan_data.ao0 = app.queued_sequence(:,1);
                            scan_data.ai1 = [scan_data.ai1; ai_res.Dev1_ai1];
                            scan_data.ao1 = app.queued_sequence(:,2);
                            scan_data.trig = app.queued_sequence(:,3);
                        elseif app.deployButton_2.UserData.ch == 1
                            scan_data.ai0 = [scan_data.ai0; ai_res.Dev1_ai0];
                            scan_data.ao0 = app.queued_sequence(:,1);
                            scan_data.trig = app.queued_sequence(:,2);
                        elseif app.deployButton_2.UserData.ch == 2
                            scan_data.ai1 = [scan_data.ai1; ai_res.Dev1_ai1];
                            scan_data.ao1 = app.queued_sequence(:,1);
                            scan_data.trig = app.queued_sequence(:,2);
                        end

                        save(strcat(fullpath,'\DAQ_data.mat'),'scan_data');
                        % log file generation
                        app.acquisition_log.Camera.Mode = 'sync with patch clamp';
                        app.acquisition_log.Camera.ExposureTime = strcat(num2str(app.exposuretimemsEditField.Value),'ms');
                        app.acquisition_log.Camera.NumberOfFrame = required_frames;
                        app.acquisition_log.Camera.AcqDuration = strcat(num2str(queue_length/app.SamplingratekHzEditField.Value/1000),'s');
                        app.acquisition_log.Camera.ROI = app.camera_BSI.obj.ROIPosition;
                        app.acquisition_log.PatchClamp.Stimulus = app.stimuluschannelsListBox.Items;
                        app.acquisition_log.PatchClamp.ScanRate = strcat(num2str(app.SamplingratekHzEditField.Value),'kHz');
                        app.acquisition_log.PatchClamp.singleCh = strcat('channel',num2str(app.deployButton_2.UserData.ch));
                        app.acquisition_log.PatchClamp.GainAndCali = app.multi_clamp_para;
                        log = app.acquisition_log;
                        save(strcat(fullpath,'\log.mat'),'log');
                    end
                    stop(app.NI_daq.daq_cam);
                    stop(app.camera_BSI.obj);
                    flush(app.NI_daq.daq_cam);
            end

            app.recordButton.Enable = 'on';
            app.abortButton.Enable = "off";

        end

        % Button pushed function: abortButton
        function abortButtonPushed(app, event)
            app.recordButton.Enable = "on";
            app.abortButton.Enable = "off";
        end

        % Button pushed function: addButton
        function addButtonPushed(app, event)
            selected_stimu = app.selectingstimulusDropDown_2.Value;
            app.stimuluschannelsListBox.Items = [app.stimuluschannelsListBox.Items selected_stimu];
        end

        % Button pushed function: removeButton
        function removeButtonPushed(app, event)
            selected_stimu = app.stimuluschannelsListBox.Value;
            for i = 1:length(app.stimuluschannelsListBox.Items)
                if strcmp(selected_stimu,app.stimuluschannelsListBox.Items{1,i})
                    break;
                end
            end
            app.stimuluschannelsListBox.Items(i) = [];
            try
                stimulus = app.stimuluschannelsListBox.Value;
                ao = feval(stimulus,app.SamplingratekHzEditField.Value);
                t = linspace(0,length(ao)/app.SamplingratekHzEditField.Value/1000,length(ao));
                last_plot = app.stimuli_vis.Children;
                delete(last_plot);
                plot(app.stimuli_vis,t,ao);
                xlim(app.stimuli_vis, [0 max(t)]);
                ylim(app.stimuli_vis,[min(ao) max(ao)]);
            catch
            end
        end

        % Button pushed function: editButton
        function editButtonPushed(app, event)
            fullpath = fullfile(app.stimu_path, strcat(app.stimuluschannelsListBox.Value, '.m'));
            edit(fullpath);
        end

        % Button pushed function: deployButton_2
        function deployButton_2_2Pushed(app, event)
            num_ch = length(app.stimuluschannelsListBox.Items);
            if num_ch>2
                msgbox('Please select at maximum 2 channels')
                return;
            end
            output_queue = [];
            last_plot = app.stimuli_vis.Children;
            delete(last_plot);
            hold(app.stimuli_vis,'on');
            y_min = 0;
            y_max = 0;
            try
                for i = 1:num_ch
                    ao_temp = feval(app.stimuluschannelsListBox.Items{1,i},app.SamplingratekHzEditField.Value);
                    t = linspace(0,length(ao_temp)/app.SamplingratekHzEditField.Value/1000,length(ao_temp));
                    plot(app.stimuli_vis,t,ao_temp);
                    xlim(app.stimuli_vis, [0 max(t)]);
                    y_min = min(y_min, min(ao_temp));
                    y_max = max(y_max, max(ao_temp));
                    output_queue = [output_queue ao_temp];
                end
                ylim(app.stimuli_vis,[y_min y_max]);
            catch
                msgbox('The scan sequences should be equally long')
                return;
            end
            try
                flush(app.NI_daq.daq_cam);
                removechannel(app.NI_daq.daq_cam,1:length(app.NI_daq.daq_cam.Channels));
            catch
            end
            app.NI_daq.daq_cam.Rate = app.SamplingratekHzEditField.Value*1000;
            tot_num_frames = max(t)*1000/app.exposuretimemsEditField.Value;
            ROI = app.camera_BSI.obj.ROIPosition;
            mem_alloc = tot_num_frames*ROI(3)*ROI(4)/2048/2048*8; % unit Mb
            sizeof_ao = length(ao_temp)*num_ch*8/1024/1024; % unit Mb, 8 bytes per double
            sizeof_DAQ = sizeof_ao*2+1; % ao*num_ch+ai*num_ch+do
            app.deployButton_2.UserData.memConfirm_wait = false;
            app.deployButton_2.UserData.memConfirm_sele = true;
            app.mem_confirm_dialog = mem_confirm(app, mem_alloc, sizeof_DAQ);% create dialog box object, which is a private property of the main object
            while ~app.deployButton_2.UserData.memConfirm_wait
                pause(0.1);
            end
            if app.deployButton_2.UserData.memConfirm_sele == false
                delete(app.mem_confirm_dialog);
                return;
            end
            sampling_per_trig = app.exposuretimemsEditField.Value*app.SamplingratekHzEditField.Value;
            seed_trig = zeros (sampling_per_trig,1);
            dutycycle_trig = 0.1;
            seed_trig(1:dutycycle_trig*sampling_per_trig) = 1;
            trig_line = seed_trig*ones(1,tot_num_frames);
            trig_line = reshape(trig_line,[sampling_per_trig*tot_num_frames 1]);
            output_queue = [output_queue trig_line];
            if num_ch>1
                for i = 1:num_ch
                    port_ao = strcat("ao",num2str(i-1));
                    port_ai = strcat("ai",num2str(i-1));
                    addoutput(app.NI_daq.daq_cam,"Dev1",port_ao,"Voltage");
                    addinput(app.NI_daq.daq_cam,"Dev1",port_ai,"Voltage");
                end
            else
                port_ao = strcat("ao",num2str(app.deployButton_2.UserData.ch-1));
                port_ai = strcat("ai",num2str(app.deployButton_2.UserData.ch-1));
                addoutput(app.NI_daq.daq_cam,"Dev1",port_ao,"Voltage");
                addinput(app.NI_daq.daq_cam,"Dev1",port_ai,"Voltage");
            end
            addoutput(app.NI_daq.daq_cam,"Dev1","port0/line0","Digital");
            preload(app.NI_daq.daq_cam,output_queue);
            app.queued_sequence = output_queue;
            delete(app.mem_confirm_dialog);
        end

        % Selection changed function: exposuremodeButtonGroup
        function exposuremodeButtonGroupSelectionChanged(app, event)
            Fcn_cam_mode_update(app);
        end

        % Value changed function: exposuretimemsEditField
        function exposuretimemsEditFieldValueChanged(app, event)
            value = app.exposuretimemsEditField.Value;
            app.StreamdurationsEditField.Value = app.NumberofframesEditField.Value*value/1000;
            app.camera_BSI.src.Exposure = value;
            app.camera_Infinity.src.EXPOSURE = value;
        end

        % Value changed function: NumberofframesEditField
        function NumberofframesEditFieldValueChanged(app, event)
            value = app.NumberofframesEditField.Value;
            exposure_time = app.exposuretimemsEditField.Value;
            app.StreamdurationsEditField.Value = value*exposure_time/1000;
            if strcmp(app.cameraButtonGroup.SelectedObject.Text,'Infinity 2')
                if strcmp(app.exposuremodeButtonGroup.SelectedObject.Text,'free recording')
                    app.StreamdurationsEditField.Value = round(value/11);
                end
            end
        end

        % Button pushed function: updateButton
        function updateButtonPushed(app, event)
            % this is a container, in which all advance protocol updating
            % can be added
            Fcn_cam_mode_update(app);
        end

        % Value changed function: LEDCheckBox
        function LEDCheckBoxValueChanged(app, event)
            value = app.LEDCheckBox.Value;
            if value % on
                app.Lamp.Color = [0,1,0];
                app.Slider.Enable = "on";
                app.powerSpinner.Enable = "on";
                app.powerSpinnerLabel.Enable = "on";
                illum_state = [1 double(app.nmlaserCheckBox.Value)];
                write(app.NI_daq.daq_illum_switch,illum_state);
            else % off
                app.Lamp.Color = [0.9,0.9,0.9];
                app.Slider.Enable = "off";
                app.powerSpinner.Enable = "off";
                app.powerSpinnerLabel.Enable = "off";
                illum_state = [0 double(app.nmlaserCheckBox.Value)];
                write(app.NI_daq.daq_illum_switch,illum_state);
            end
        end

        % Value changed function: nmlaserCheckBox
        function nmlaserCheckBoxValueChanged(app, event)
            value = app.nmlaserCheckBox.Value;
            if value % on
                app.Lamp2.Color = [0,1,0];
                app.Slider2.Enable = "on";
                app.powerSpinner_2Label.Enable = "on";
                app.powerSpinner_2.Enable = "on";
                illum_state = [double(app.LEDCheckBox.Value) 1];
                write(app.NI_daq.daq_illum_switch,illum_state);
            else % off
                app.Lamp2.Color = [0.9,0.9,0.9];
                app.Slider2.Enable = "off";
                app.powerSpinner_2.Enable = "off";
                app.powerSpinner_2Label.Enable = "off";
                illum_state = [double(app.LEDCheckBox.Value) 0];
                write(app.NI_daq.daq_illum_switch,illum_state);
            end
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            LED_power = changingValue/100*10;
            app.powerSpinner.Value = changingValue;
            laser_power = app.Slider2.Value/100*5;
            power_cmd = [LED_power laser_power];
            write(app.NI_daq.daq_illum_inten,power_cmd);
        end

        % Value changing function: Slider2
        function Slider2ValueChanging(app, event)
            changingValue = event.Value;
            laser_power = changingValue/100*5;
            app.powerSpinner_2.Value = changingValue;
            LED_power = app.Slider.Value/100*10;
            power_cmd = [LED_power laser_power];
            write(app.NI_daq.daq_illum_inten,power_cmd);
        end

        % Value changed function: powerSpinner
        function powerSpinnerValueChanged(app, event)
            value = app.powerSpinner.Value;
            app.Slider.Value = value;
            LED_power = value/100*10;
            laser_power = app.Slider2.Value/100*5;
            power_cmd = [LED_power laser_power];
            write(app.NI_daq.daq_illum_inten,power_cmd);
        end

        % Value changed function: powerSpinner_2
        function powerSpinner_2ValueChanged(app, event)
            value = app.powerSpinner_2.Value;
            app.Slider2.Value = value;
            LED_power = app.Slider.Value/100*10;
            laser_power = value/100*5;
            power_cmd = [LED_power laser_power];
            write(app.NI_daq.daq_illum_inten,power_cmd);
        end

        % Button pushed function: zeropipetteoffsetButton
        function zeropipetteoffsetButtonPushed(app, event)
            %update pipette offset on-fly
            app.zeropipetteoffsetButton.UserData = true;
        end

        % Button pushed function: liveButton
        function liveButtonPushed(app, event)
            app.zeropipetteoffsetButton.UserData = false;
            app.gigasealButton.UserData.flag = false;
            if ~app.MC_dev.livemode
                app.liveButton.Text = "stop";
                app.zeropipetteoffsetButton.Enable = "on";
                app.wholecellCheckBox.Enable = "on";
                app.gigasealButton.Enable = "on";
                app.MC_dev.livemode = true;
                try
                    flush(app.NI_daq.daq_cam);
                    removechannel(app.NI_daq.daq_cam,1:length(app.NI_daq.daq_cam.Channels));
                catch
                end
                Fcn_live_stimuli(app); % channel and VC mode are set in this function
                State_whole_cell_pre = app.wholecellCheckBox.Value;
                while app.MC_dev.livemode
                    ai = readwrite(app.NI_daq.daq_cam, app.queued_sequence);
                    t = seconds(ai.Time);
                    if strcmp(app.channelButtonGroup.SelectedObject.Text, 'ch1')
                        data = ai.Dev1_ai0;
                        %%%%%% calculate giga seal resistance
                        if app.zeropipetteoffsetButton.UserData
                            app.zeropipetteoffsetButton.UserData = Fcn_auto_pipette_os(app.MC_dev.handle,app.queued_sequence, data);
                        end
                        if app.gigasealButton.UserData.flag && app.gigasealButton.UserData.count>0
                            app.gigasealButton.UserData.data = [app.gigasealButton.UserData.data, data];
                            app.gigasealButton.UserData.count = app.gigasealButton.UserData.count-1;
                        elseif app.gigasealButton.UserData.flag
                            idx = app.queued_sequence>0;
                            aver_output = mean(mean(app.gigasealButton.UserData.data(idx,:)));
                            aver_input = mean(app.queued_sequence(idx));
                            R_GigaSeal = Fcn_calc_R(app,aver_input,aver_output);
                            app.RMEditField_3.Value = R_GigaSeal;
                            app.multi_clamp_para.ch1.GigaSealR = strcat(num2str(R_GigaSeal),'MΩ');
                            app.gigasealButton.UserData.flag = false;
                        end
                        %%%%%% visualization
                        cla(app.DAQ_vis_graph.ch1.UIAxes);
                        cla(app.DAQ_vis_graph.ch1.UIAxes2);
                        plot(app.DAQ_vis_graph.ch1.UIAxes2,t,data,"red");
                        plot(app.DAQ_vis_graph.ch1.UIAxes,t,app.queued_sequence,"blue");
                        xlim(app.DAQ_vis_graph.ch1.UIAxes,[0 max(t)]);
                        xlim(app.DAQ_vis_graph.ch1.UIAxes2,[0 max(t)]);
                        ylim(app.DAQ_vis_graph.ch1.UIAxes2,[min(data) max(data)]);
                    else
                        data = ai.Dev1_ai1;
                        %%%%%% calculate giga seal resistance
                        if app.zeropipetteoffsetButton.UserData
                            app.zeropipetteoffsetButton.UserData = Fcn_auto_pipette_os(app.MC_dev.handle,app.queued_sequence, data);
                        end
                        if app.gigasealButton.UserData.flag && app.gigasealButton.UserData.count>0
                            app.gigasealButton.UserData.data = [app.gigasealButton.UserData.data, data];
                            app.gigasealButton.UserData.count = app.gigasealButton.UserData.count-1;
                        elseif app.gigasealButton.UserData.flag
                            idx = app.queued_sequence>0;
                            aver_output = mean(mean(app.gigasealButton.UserData.data(idx,:)));
                            aver_input = mean(app.queued_sequence(idx));
                            R_GigaSeal = Fcn_calc_R(app,aver_input,aver_output);
                            app.RMEditField_3.Value = R_GigaSeal;
                            app.multi_clamp_para.ch2.GigaSealR = strcat(num2str(R_GigaSeal),'MΩ');
                            app.gigasealButton.UserData.flag = false;
                        end
                        %%%%%% visualization
                        cla(app.DAQ_vis_graph.ch2.UIAxes);
                        cla(app.DAQ_vis_graph.ch2.UIAxes2);
                        plot(app.DAQ_vis_graph.ch2.UIAxes2,t,data,"red");
                        plot(app.DAQ_vis_graph.ch2.UIAxes,t,app.queued_sequence,"blue");
                        xlim(app.DAQ_vis_graph.ch2.UIAxes,[0 max(t)]);
                        xlim(app.DAQ_vis_graph.ch2.UIAxes2,[0 max(t)]);
                        ylim(app.DAQ_vis_graph.ch2.UIAxes2,[min(data) max(data)]);
                    end
                    flush(app.NI_daq.daq_cam);
                    %%%%%% whole cell compensation
                    State_whole_cell_cur = app.wholecellCheckBox.Value;
                    if (~State_whole_cell_pre && State_whole_cell_cur)
                        % switch on whole cell and auto cali and read out RC
                        MC_set_whole_cell(app.MC_dev.handle,1);
                        %                         MC_auto_whole_cell(app.MC_dev.handle);
                        pause(3);
                        app.CpFEditField_2.Value = round(MC_get_whole_cell_cap(app.MC_dev.handle)*10^15)/1000;
                        app.RMEditField_4.Value = round(MC_get_whole_cell_resis(app.MC_dev.handle)/10^3)/1000;
                        if strcmp(app.channelButtonGroup.SelectedObject.Text, 'ch1')
                            app.multi_clamp_para.ch1.WholeCellR = app.RMEditField_4.Value;
                            app.multi_clamp_para.ch1.WholeCellC = app.CpFEditField_2.Value;
                        else
                            app.multi_clamp_para.ch2.WholeCellR = app.RMEditField_4.Value;
                            app.multi_clamp_para.ch2.WholeCellC = app.CpFEditField_2.Value;
                        end
                    elseif (State_whole_cell_pre && ~State_whole_cell_cur)
                        % switch off whole cell
                        MC_set_whole_cell(app.MC_dev.handle,0);
                    end
                    State_whole_cell_pre = State_whole_cell_cur;
                end
            else
                app.zeropipetteoffsetButton.Enable = "off";
                app.wholecellCheckBox.Enable = "off";
                app.gigasealButton.Enable = "off";
                app.liveButton.Text = "live";
                app.MC_dev.livemode = false;
            end
        end

        % Button pushed function: sethomeButton
        function sethomeButtonPushed(app, event)
            stop(app.para_obj.POS_timer);
            writeline(app.obj_client,'xy_ZERO');
            app.obj_client.UserData = readline(app.obj_client);
            writeline(app.obj_client,'manip1_PX 0');
            app.obj_client.UserData = readline(app.obj_client);
            writeline(app.obj_client,'manip1_PY 0');
            app.obj_client.UserData = readline(app.obj_client);
            writeline(app.obj_client,'manip2_PX 0');
            app.obj_client.UserData = readline(app.obj_client);
            writeline(app.obj_client,'manip2_PY 0');
            app.obj_client.UserData = readline(app.obj_client);
            app.xSpinner.Value = 0;
            app.ySpinner.Value = 0;
            num_items = length(app.selectposDropDown.Items);
            for i = 1:num_items
                app.selectposDropDown.Items(1) = [];
            end
            Fcn_plot_cur_pos(app);
            try
                delete(app.UIAxes4.UserData.select_pos);
            catch
            end
            start(app.para_obj.POS_timer);
        end

        % Button pushed function: gotohomeButton
        function gotohomeButtonPushed(app, event)
            stop(app.para_obj.POS_timer);
            writeline(app.obj_client,'xy_ABS 0 0');
            app.obj_client.UserData = readline(app.obj_client);
            writeline(app.obj_client,'manip1_ABS 0 0');
            app.obj_client.UserData = readline(app.obj_client);
            writeline(app.obj_client,'manip2_ABS 0 0');
            app.obj_client.UserData = readline(app.obj_client);
            start(app.para_obj.POS_timer);
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            app.xSpinner.Step = value;
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
            app.ySpinner.Step = value;
        end

        % Value changed function: xSpinner
        function xSpinnerValueChanged(app, event)
            stop(app.para_obj.POS_timer);
            xvalue = num2str(-app.xSpinner.Value*10);
            yvalue = num2str(app.ySpinner.Value*10);
            cmd = strcat("xy_ABS ",xvalue," ",yvalue);
            cmd = convertStringsToChars(cmd);
            writeline(app.obj_client,cmd);
            app.obj_client.UserData = readline(app.obj_client);
            start(app.para_obj.POS_timer);
        end

        % Value changed function: ySpinner
        function ySpinnerValueChanged(app, event)
            stop(app.para_obj.POS_timer);
            xvalue = num2str(-app.xSpinner.Value*10);
            yvalue = num2str(app.ySpinner.Value*10);
            cmd = strcat("xy_ABS ",xvalue," ",yvalue);
            cmd = convertStringsToChars(cmd);
            writeline(app.obj_client,cmd);
            app.obj_client.UserData = readline(app.obj_client);
            start(app.para_obj.POS_timer);
        end

        % Button pushed function: registercurrentposButton
        function registercurrentposButtonPushed(app, event)
            x = num2str(app.xSpinner.Value);
            y = num2str(app.ySpinner.Value);
            new_item = convertStringsToChars(strcat("x: ", x, " µm, y: ", y, " µm"));
            app.selectposDropDown.Items = [app.selectposDropDown.Items new_item];
            try
                delete(app.UIAxes4.UserData.select_pos);
            catch
            end
        end

        % Button pushed function: deleteselectedposButton
        function deleteselectedposButtonPushed(app, event)
            selected_pos = app.selectposDropDown.Value;
            for i = 1:length(app.selectposDropDown.Items)
                if strcmp(selected_pos,app.selectposDropDown.Items{1,i})
                    break;
                end
            end
            app.selectposDropDown.Items(i) = [];
            try
                delete(app.UIAxes4.UserData.select_pos);
            catch
            end
        end

        % Button pushed function: gotoselectedposButton
        function gotoselectedposButtonPushed(app, event)
            stop(app.para_obj.POS_timer);
            selected_pos = app.selectposDropDown.Value;
            x_pos_idx = strfind(selected_pos,'x:');
            y_pos_idx = strfind(selected_pos,'y:');
            unit_pos = strfind(selected_pos,'µm');
            x_pos = str2double(selected_pos(x_pos_idx+3:unit_pos(1)-2));
            y_pos = str2double(selected_pos(y_pos_idx+3:unit_pos(2)-2));
            xvalue = num2str(-x_pos*10);
            yvalue = num2str(y_pos*10);
            cmd = strcat("xy_ABS ",xvalue," ",yvalue);
            cmd = convertStringsToChars(cmd);
            writeline(app.obj_client,cmd);
            app.obj_client.UserData = readline(app.obj_client);
            try
                delete(app.UIAxes4.UserData.select_pos);
            catch
            end
            start(app.para_obj.POS_timer);
        end

        % Value changed function: selectposDropDown
        function selectposDropDownValueChanged(app, event)
            selected_pos = app.selectposDropDown.Value;
            x_pos_idx = strfind(selected_pos,'x:');
            y_pos_idx = strfind(selected_pos,'y:');
            unit_pos = strfind(selected_pos,'µm');
            x_pos = str2double(selected_pos(x_pos_idx+3:unit_pos(1)-2))/1000;
            y_pos = str2double(selected_pos(y_pos_idx+3:unit_pos(2)-2))/1000;
            try
                delete(app.UIAxes4.UserData.select_pos);
            catch
            end
            app.UIAxes4.UserData.select_pos = plot(app.UIAxes4,x_pos,y_pos,'o','MarkerEdgeColor','r',...
                'MarkerFaceColor','r');
        end

        % Button pushed function: followButton
        function followButtonPushed(app, event)
            stop(app.para_obj.POS_timer);
            writeline(app.obj_client,'xy_POS');  % left -  up - XY
            app.obj_client.UserData = readline(app.obj_client);         % left -  up + manip2
            xyz_pos = regexp(app.obj_client.UserData,'\t','split');
            x1 = num2str(-str2num(xyz_pos(1)));
            %             y = str2num(xyz_pos(2));
            cmd = strcat("manip1_ABS ",x1," ",xyz_pos(2));
            cmd = convertStringsToChars(cmd);
            writeline(app.obj_client,cmd);
            app.obj_client.UserData = readline(app.obj_client);
            cmd = strcat("manip2_ABS ",x1," ",xyz_pos(2));
            cmd = convertStringsToChars(cmd);
            writeline(app.obj_client,cmd);
            app.obj_client.UserData = readline(app.obj_client);
            start(app.para_obj.POS_timer);
        end

        % Selection changed function: cameraButtonGroup
        function cameraButtonGroupSelectionChanged(app, event)
            selectedCam = app.cameraButtonGroup.SelectedObject.Text;
            write(app.NI_daq.daq_flip_M,1);
            pause(0.1);
            write(app.NI_daq.daq_flip_M,0);
            switch selectedCam
                case 'Infinity 2'
                    app.maxSlider.Limits = [1 2^12-1];
                    app.maxSlider.Value = 2^12-1;
                    app.minSlider.Limits = [1 10000];
                    app.minSlider.Value = 1;
                    app.binsizeDropDown.Visible = "off";
                    app.binsizeDropDownLabel.Visible = "off";
                    app.ROIDropDown.Visible = "off";
                    app.ROIDropDownLabel.Visible = "off";
                    app.lefttopwidthheightLabel.Visible = "off";
                    app.ROIselectCheckBox.Visible = "off";
                    app.gainEditField.Visible = "on";
                    app.gainEditFieldLabel.Visible = "on";
                    try
                        hFig = findobj('Tag','live_stream');
                        delete(hFig);
                        app.closeButton.Enable = "off";
                    catch
                    end
                case 'BSI Express'
                    grey_depth_idx = strfind(app.camera_BSI.PmDeviceFormat,'-Bit');
                    grey_depth = str2double(app.camera_BSI.PmDeviceFormat(grey_depth_idx-2:grey_depth_idx-1));
                    app.maxSlider.Limits = [1 10000];
                    app.maxSlider.Value = 10000;%2^grey_depth-1;
                    app.minSlider.Limits = [1 10000];
                    app.minSlider.Value = 1;
                    app.binsizeDropDown.Visible = "on";
                    app.binsizeDropDownLabel.Visible = "on";
                    app.ROIDropDown.Visible = "on";
                    app.ROIDropDownLabel.Visible = "on";
                    app.lefttopwidthheightLabel.Visible = "on";
                    app.ROIselectCheckBox.Visible = "on";
                    app.gainEditField.Visible = "off";
                    app.gainEditFieldLabel.Visible = "off";
            end
        end

        % Value changed function: gainEditField
        function gainEditFieldValueChanged(app, event)
            value = app.gainEditField.Value;
            app.camera_Infinity.src.GAIN = value;
        end

        % Value changing function: maxSlider
        function maxSliderValueChanging(app, event)
            changingValue = event.Value;
            if app.minSlider.Value>changingValue
                app.minSlider.Value = changingValue ;
            end
            app.maxSlider.Value = changingValue;
            if app.holdCheckBox.Value == 1
                app.maxSlider_2.Value = app.maxSlider.Value;
            end
        end

        % Value changing function: minSlider
        function minSliderValueChanging(app, event)
            changingValue = event.Value;
            if app.maxSlider.Value<changingValue
                app.maxSlider.Value = changingValue;
            end
            app.minSlider.Value = changingValue;
            if app.holdCheckBox.Value == 1
                app.minSlider_2.Value = app.minSlider.Value;
            end
        end

        % Value changed function: holdCheckBox
        function holdCheckBoxValueChanged(app, event)
            value = app.holdCheckBox.Value;
            if value == 1
                app.autocontrastCheckBox.Value = 0;
                app.maxSlider.Enable = "on";
                app.minSlider.Enable = "on";
                app.minSlider_2.Limits = app.minSlider.Limits;
                app.maxSlider_2.Limits = app.maxSlider.Limits;

            end
        end

        % Button pushed function: setworkposButton
        function setworkposButtonPushed(app, event)
            stop(app.para_obj.POS_timer);
            writeline(app.obj_client,'manip1_PZ 0');
            app.obj_client.UserData = readline(app.obj_client);
            start(app.para_obj.POS_timer);
        end

        % Button pushed function: homeButton
        function homeButtonPushed(app, event)
            stop(app.para_obj.POS_timer);
            cmd = "manip1_ABSZ 10000";
            cmd = convertStringsToChars(cmd);
            writeline(app.obj_client,cmd);
            app.obj_client.UserData = readline(app.obj_client);
            start(app.para_obj.POS_timer);
        end

        % Button pushed function: workButton
        function workButtonPushed(app, event)
            stop(app.para_obj.POS_timer);
            cmd = "manip1_ABSZ 0";
            cmd = convertStringsToChars(cmd);
            writeline(app.obj_client,cmd);
            app.obj_client.UserData = readline(app.obj_client);
            start(app.para_obj.POS_timer);
        end

        % Button pushed function: snapshotButton
        function snapshotButtonPushed(app, event)
            fullpath = strcat(app.DatasavingdirEditField.Value,app.FilenameEditField.Value);
            if ~exist(fullpath, 'dir')
                mkdir(fullpath);
            end
            if strcmp(app.cameraButtonGroup.SelectedObject.Text,'Infinity 2')
                frame = getsnapshot(app.camera_Infinity.obj);
                img_out = imrotate(frame,90);
                img_out = flip(img_out,2);
                ROI = app.camera_Infinity.obj.ROIPosition;
                img_out_wf = img_out(ROI(1)+1:ROI(1)+ROI(3),ROI(2)+1:ROI(2)+ROI(4));
                imwrite(img_out_wf,strcat(fullpath,'\',app.FilenameEditField.Value,'_snapshot_widefield.tiff'),"WriteMode","append");
            else
                img_out_lf = getsnapshot(app.camera_BSI.obj);
                imwrite(img_out_lf,strcat(fullpath,'\',app.FilenameEditField.Value,'_snapshot_lightfield.tiff'),"WriteMode","append");
            end

        end

        % Value changing function: minSlider_2
        function minSlider_2ValueChanging(app, event)
            changingValue = event.Value;
            app.minSlider_2.Value = changingValue;
        end

        % Value changing function: maxSlider_2
        function maxSlider_2ValueChanging(app, event)
            changingValue = event.Value;
            app.maxSlider_2.Value = changingValue;
        end

        % Button pushed function: gigasealButton
        function gigasealButtonPushed(app, event)
            app.gigasealButton.UserData.flag = true;
            app.gigasealButton.UserData.count = 10;
            app.gigasealButton.UserData.data = [];
        end

        % Selection changed function: channelButtonGroup
        function channelButtonGroupSelectionChanged(app, event)
            if strcmp(app.channelButtonGroup.SelectedObject.Text, 'ch1')
                app.multi_clamp_para.ch2.GigaSealR = app.RMEditField_3.Value;
                app.multi_clamp_para.ch2.WholeCellR =app.RMEditField_4.Value;
                app.multi_clamp_para.ch2.WholeCellC =app.CpFEditField_2.Value;
                app.multi_clamp_para.ch2.VC_R = app.VCEditField.Value;
                app.multi_clamp_para.ch2.CC_R = app.CCEditField.Value;
                app.multi_clamp_para.ch2.VC_cmd = app.VCcmdmVVEditField.Value;
                app.multi_clamp_para.ch2.CC_cmd = app.CCcmdpAVEditField.Value;
                app.RMEditField_3.Value = app.multi_clamp_para.ch1.GigaSealR;
                app.RMEditField_4.Value = app.multi_clamp_para.ch1.WholeCellR;
                app.CpFEditField_2.Value = app.multi_clamp_para.ch1.WholeCellC;
                app.VCEditField.Value = app.multi_clamp_para.ch1.VC_R;
                app.CCEditField.Value = app.multi_clamp_para.ch1.CC_R;
                app.VCcmdmVVEditField.Value = app.multi_clamp_para.ch1.VC_cmd;
                app.CCcmdpAVEditField.Value = app.multi_clamp_para.ch1.CC_cmd;
            else
                app.multi_clamp_para.ch1.GigaSealR = app.RMEditField_3.Value;
                app.multi_clamp_para.ch1.WholeCellR =app.RMEditField_4.Value;
                app.multi_clamp_para.ch1.WholeCellC =app.CpFEditField_2.Value;
                app.multi_clamp_para.ch1.VC_R = app.VCEditField.Value;
                app.multi_clamp_para.ch1.CC_R = app.CCEditField.Value;
                app.multi_clamp_para.ch1.VC_cmd = app.VCcmdmVVEditField.Value;
                app.multi_clamp_para.ch1.CC_cmd = app.CCcmdpAVEditField.Value;
                app.RMEditField_3.Value = app.multi_clamp_para.ch2.GigaSealR;
                app.RMEditField_4.Value = app.multi_clamp_para.ch2.WholeCellR;
                app.CpFEditField_2.Value = app.multi_clamp_para.ch2.WholeCellC;
                app.VCEditField.Value = app.multi_clamp_para.ch2.VC_R;
                app.CCEditField.Value = app.multi_clamp_para.ch2.CC_R;
                app.VCcmdmVVEditField.Value = app.multi_clamp_para.ch2.VC_cmd;
                app.CCcmdpAVEditField.Value = app.multi_clamp_para.ch2.CC_cmd;
            end
        end

        % Value changed function: stimuluschannelsListBox
        function stimuluschannelsListBoxValueChanged(app, event)
            stimulus = app.stimuluschannelsListBox.Value;
            ao = feval(stimulus,app.SamplingratekHzEditField.Value);
            t = linspace(0,length(ao)/app.SamplingratekHzEditField.Value/1000,length(ao));
            last_plot = app.stimuli_vis.Children;
            delete(last_plot);
            plot(app.stimuli_vis,t,ao);
            xlim(app.stimuli_vis, [0 max(t)]);
            ylim(app.stimuli_vis,[min(ao) max(ao)]);
        end

        % Value changed function: reflineCheckBox
        function reflineCheckBoxValueChanged(app, event)
            if app.reflineCheckBox.Value
                switch app.cameraButtonGroup.SelectedObject.Text
                    case 'Infinity 2'
                        x = linspace(1,512,512);
                        y = ones(size(x))*256;
                        hold(app.camera_BSI.ImgAx,"on");
                        h1 = plot(app.camera_BSI.ImgAx,x,y,'LineWidth',1,'LineStyle','--','Color',"r");
                        h2 = plot(app.camera_BSI.ImgAx,y,x,'LineWidth',1,'LineStyle','--','Color',"r");
                        app.camera_Infinity.refline1 = h1;
                        app.camera_Infinity.refline2 = h2;
                        hold(app.camera_BSI.ImgAx,"off");
                    case 'BSI Express'
                        ROI = app.camera_BSI.obj.ROIPosition;
                        img_W = ROI(3);
                        img_H = ROI(4);
                        x = linspace(1,img_W,img_W);
                        y = ones(size(x))*img_H/2;
                        hold(app.camera_BSI.ImgAx,"on");
                        h1 = plot(app.camera_BSI.ImgAx,x,y,'LineWidth',1,'LineStyle','--','Color',"r");
                        h2 = plot(app.camera_BSI.ImgAx,y,x,'LineWidth',1,'LineStyle','--','Color',"r");
                        app.camera_BSI.refline1 = h1;
                        app.camera_BSI.refline2 = h2;
                        hold(app.camera_BSI.ImgAx,"off");
                end
            else
                switch app.cameraButtonGroup.SelectedObject.Text
                    case 'Infinity 2'
                        h1 = app.camera_Infinity.refline1;
                        h2 = app.camera_Infinity.refline2;
                        delete(h1);
                        delete(h2);
                    case 'BSI Express'
                        h1 = app.camera_BSI.refline1;
                        h2 = app.camera_BSI.refline2;
                        delete(h1);
                        delete(h2);
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.cameraTab = uitab(app.TabGroup);
            app.cameraTab.Title = 'camera';
            app.TabGroup2 = uitabgroup(app.cameraTab);
            app.liveTab = uitab(app.TabGroup2);
            app.liveTab.Title = 'live';
            app.UIAxes2 = uiaxes(app.liveTab);
            title(app.UIAxes2, 'Histogram')
            app.UIAxes2.XTick = [0 1];
            app.UIAxes2.XTickLabel = {'0'; '65535'};
            app.UIAxes2.YTick = [];
            app.exposuretimemsEditFieldLabel = uilabel(app.liveTab);
            app.exposuretimemsEditFieldLabel.Text = 'exposure time (ms)';
            app.exposuretimemsEditField = uieditfield(app.liveTab, 'numeric');
            app.exposuretimemsEditField.Limits = [1 1000];
            app.exposuretimemsEditField.ValueChangedFcn = createCallbackFcn(app, @exposuretimemsEditFieldValueChanged, true);
            app.exposuretimemsEditField.Value = 50;
            app.binsizeDropDownLabel = uilabel(app.liveTab);
            app.binsizeDropDownLabel.Visible = 'off';
            app.binsizeDropDownLabel.Text = 'bin size';
            app.binsizeDropDown = uidropdown(app.liveTab);
            app.binsizeDropDown.Items = {'1 × 1', '2 × 2'};
            app.binsizeDropDown.Visible = 'off';
            app.binsizeDropDown.Value = '1 × 1';
            app.ROIDropDownLabel = uilabel(app.liveTab);
            app.ROIDropDownLabel.Visible = 'off';
            app.ROIDropDownLabel.Text = 'ROI';
            app.ROIDropDown = uidropdown(app.liveTab);
            app.ROIDropDown.Items = {'1,1,2048,2048', '625,625,800,800', '769,769,512,512', '897,897,256,256'};
            app.ROIDropDown.Visible = 'off';
            app.ROIDropDown.Value = '1,1,2048,2048';
            app.live_start_ = uibutton(app.liveTab, 'push');
            app.live_start_.ButtonPushedFcn = createCallbackFcn(app, @live_start_Pushed, true);
            app.live_start_.Text = 'start';
            app.minSliderLabel = uilabel(app.liveTab);
            app.minSliderLabel.Text = 'min';
            app.minSlider = uislider(app.liveTab);
            app.minSlider.MajorTicks = [];
            app.minSlider.ValueChangingFcn = createCallbackFcn(app, @minSliderValueChanging, true);
            app.minSlider.MinorTicks = [];
            app.maxSliderLabel = uilabel(app.liveTab);
            app.maxSliderLabel.Text = 'max';
            app.maxSlider = uislider(app.liveTab);
            app.maxSlider.MajorTicks = [];
            app.maxSlider.ValueChangingFcn = createCallbackFcn(app, @maxSliderValueChanging, true);
            app.maxSlider.MinorTicks = [];
            app.maxSlider.Value = 100;
            app.autocontrastCheckBox = uicheckbox(app.liveTab);
            app.autocontrastCheckBox.ValueChangedFcn = createCallbackFcn(app, @autocontrastCheckBoxValueChanged, true);
            app.autocontrastCheckBox.Text = 'auto contrast';
            app.HaltButton = uibutton(app.liveTab, 'push');
            app.HaltButton.ButtonPushedFcn = createCallbackFcn(app, @HaltButtonPushed, true);
            app.HaltButton.Enable = 'off';
            app.HaltButton.Text = 'halt';
            app.closeButton = uibutton(app.liveTab, 'push');
            app.closeButton.ButtonPushedFcn = createCallbackFcn(app, @closeButtonPushed, true);
            app.closeButton.Enable = 'off';
            app.closeButton.Text = 'close';
            app.ROIselectCheckBox = uicheckbox(app.liveTab);
            app.ROIselectCheckBox.ValueChangedFcn = createCallbackFcn(app, @ROIselectCheckBoxValueChanged, true);
            app.ROIselectCheckBox.Visible = 'off';
            app.ROIselectCheckBox.Text = 'ROI select';
            app.lefttopwidthheightLabel = uilabel(app.liveTab);
            app.lefttopwidthheightLabel.Visible = 'off';
            app.lefttopwidthheightLabel.Text = 'left, top, width, height';
            app.cameraButtonGroup = uibuttongroup(app.liveTab);
            app.cameraButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @cameraButtonGroupSelectionChanged, true);
            app.cameraButtonGroup.Title = '   camera';
            app.Infinity2Button = uiradiobutton(app.cameraButtonGroup);
            app.Infinity2Button.Text = 'Infinity 2';
            app.Infinity2Button.Value = true;
            app.BSIExpressButton = uiradiobutton(app.cameraButtonGroup);
            app.BSIExpressButton.Text = 'BSI Express';
            app.gainEditFieldLabel = uilabel(app.liveTab);
            app.gainEditFieldLabel.Text = 'gain';
            app.gainEditField = uieditfield(app.liveTab, 'numeric');
            app.gainEditField.Limits = [2 10];
            app.gainEditField.ValueChangedFcn = createCallbackFcn(app, @gainEditFieldValueChanged, true);
            app.gainEditField.Value = 2;
            app.holdCheckBox = uicheckbox(app.liveTab);
            app.holdCheckBox.ValueChangedFcn = createCallbackFcn(app, @holdCheckBoxValueChanged, true);
            app.holdCheckBox.Text = 'hold';
            app.reflineCheckBox = uicheckbox(app.liveTab);
            app.reflineCheckBox.ValueChangedFcn = createCallbackFcn(app, @reflineCheckBoxValueChanged, true);
            app.reflineCheckBox.Text = 'ref line';
            app.WFaqTab = uitab(app.TabGroup2);
            app.WFaqTab.Title = 'WF aq';
            app.StreamdurationsEditFieldLabel = uilabel(app.WFaqTab);
            app.StreamdurationsEditFieldLabel.Text = 'Stream duration  (s)';
            app.StreamdurationsEditField = uieditfield(app.WFaqTab, 'numeric');
            app.StreamdurationsEditField.ValueChangedFcn = createCallbackFcn(app, @StreamdurationsEditFieldValueChanged, true);
            app.NumberofframesEditFieldLabel = uilabel(app.WFaqTab);
            app.NumberofframesEditFieldLabel.Text = 'Number of frames';
            app.NumberofframesEditField = uieditfield(app.WFaqTab, 'numeric');
            app.NumberofframesEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofframesEditFieldValueChanged, true);
            app.recordButton = uibutton(app.WFaqTab, 'push');
            app.recordButton.ButtonPushedFcn = createCallbackFcn(app, @recordButtonPushed, true);
            app.recordButton.Text = 'record';
            app.DatasavingdirEditFieldLabel = uilabel(app.WFaqTab);
            app.DatasavingdirEditFieldLabel.Text = 'Data saving dir';
            app.DatasavingdirEditField = uieditfield(app.WFaqTab, 'text');
            app.DatasavingdirEditField.Value = 'D:\Data\Hao\temp\';
            app.abortButton = uibutton(app.WFaqTab, 'push');
            app.abortButton.ButtonPushedFcn = createCallbackFcn(app, @abortButtonPushed, true);
            app.abortButton.Enable = 'off';
            app.abortButton.Text = 'abort';
            app.FilenameEditFieldLabel = uilabel(app.WFaqTab);
            app.FilenameEditFieldLabel.Text = 'File name';
            app.FilenameEditField = uieditfield(app.WFaqTab, 'text');
            app.FilenameEditField.Value = 'test';
            app.exposuremodeButtonGroup = uibuttongroup(app.WFaqTab);
            app.exposuremodeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @exposuremodeButtonGroupSelectionChanged, true);
            app.exposuremodeButtonGroup.Title = 'exposure mode';
            app.freerecordingButton_2 = uiradiobutton(app.exposuremodeButtonGroup);
            app.freerecordingButton_2.Text = 'free recording';
            app.freerecordingButton_2.Value = true;
            app.syncwithpatchclampButton = uiradiobutton(app.exposuremodeButtonGroup);
            app.syncwithpatchclampButton.Text = 'sync with patch clamp';
            app.minSlider_2Label = uilabel(app.WFaqTab);
            app.minSlider_2Label.Text = 'min';
            app.minSlider_2 = uislider(app.WFaqTab);
            app.minSlider_2.MajorTicks = [];
            app.minSlider_2.MajorTickLabels = {''};
            app.minSlider_2.ValueChangingFcn = createCallbackFcn(app, @minSlider_2ValueChanging, true);
            app.minSlider_2.MinorTicks = [];
            app.maxSlider_2Label = uilabel(app.WFaqTab);
            app.maxSlider_2Label.Text = 'max';
            app.maxSlider_2 = uislider(app.WFaqTab);
            app.maxSlider_2.MajorTicks = [];
            app.maxSlider_2.MajorTickLabels = {''};
            app.maxSlider_2.ValueChangingFcn = createCallbackFcn(app, @maxSlider_2ValueChanging, true);
            app.maxSlider_2.MinorTicks = [];
            app.updateButton = uibutton(app.WFaqTab, 'push');
            app.updateButton.ButtonPushedFcn = createCallbackFcn(app, @updateButtonPushed, true);
            app.updateButton.Text = 'update';
            app.snapshotButton = uibutton(app.WFaqTab, 'push');
            app.snapshotButton.ButtonPushedFcn = createCallbackFcn(app, @snapshotButtonPushed, true);
            app.snapshotButton.Text = 'snapshot';
            app.SMaqTab = uitab(app.TabGroup2);
            app.SMaqTab.Title = 'SM aq';
            app.patchclampTab = uitab(app.TabGroup);
            app.patchclampTab.Title = 'patch clamp';
            app.TabGroup3 = uitabgroup(app.patchclampTab);
            app.prtcTab = uitab(app.TabGroup3);
            app.prtcTab.Title = 'prtc';
            app.stimuli_vis = uiaxes(app.prtcTab);
            title(app.stimuli_vis, 'stimuli')
            xlabel(app.stimuli_vis, 'time / s')
            ylabel(app.stimuli_vis, 'voltage / v')
            zlabel(app.stimuli_vis, 'Z')
            app.stimuluschannelsListBoxLabel = uilabel(app.prtcTab);
            app.stimuluschannelsListBoxLabel.Text = 'stimulus channels';
            app.selectingstimulusDropDown_2 = uidropdown(app.prtcTab);
            app.selectingstimulusDropDown_2.Items = {};
            app.selectingstimulusDropDown_2.Value = {};
            app.selectingstimulusDropDown_2Label = uilabel(app.prtcTab);
            app.selectingstimulusDropDown_2Label.Text = 'selecting stimulus';
            app.addButton = uibutton(app.prtcTab, 'push');
            app.addButton.ButtonPushedFcn = createCallbackFcn(app, @addButtonPushed, true);
            app.addButton.Text = 'add';
            app.removeButton = uibutton(app.prtcTab, 'push');
            app.removeButton.ButtonPushedFcn = createCallbackFcn(app, @removeButtonPushed, true);
            app.removeButton.Text = 'remove';
            app.editButton = uibutton(app.prtcTab, 'push');
            app.editButton.ButtonPushedFcn = createCallbackFcn(app, @editButtonPushed, true);
            app.editButton.Text = 'edit';
            app.stimuluschannelsListBox = uilistbox(app.prtcTab);
            app.stimuluschannelsListBox.Items = {};
            app.stimuluschannelsListBox.ValueChangedFcn = createCallbackFcn(app, @stimuluschannelsListBoxValueChanged, true);
            app.stimuluschannelsListBox.Value = {};
            app.deployButton_2 = uibutton(app.prtcTab, 'push');
            app.deployButton_2.ButtonPushedFcn = createCallbackFcn(app, @deployButton_2_2Pushed, true);
            app.deployButton_2.Text = 'deploy';
            app.SamplingratekHzEditFieldLabel = uilabel(app.prtcTab);
            app.SamplingratekHzEditFieldLabel.Text = 'Sampling rate (kHz)';
            app.SamplingratekHzEditField = uieditfield(app.prtcTab, 'numeric');
            app.SamplingratekHzEditField.Value = 10;
            app.caliTab = uitab(app.TabGroup3);
            app.caliTab.Title = 'cali';
            app.zeropipetteoffsetButton = uibutton(app.caliTab, 'push');
            app.zeropipetteoffsetButton.ButtonPushedFcn = createCallbackFcn(app, @zeropipetteoffsetButtonPushed, true);
            app.zeropipetteoffsetButton.Enable = 'off';
            app.zeropipetteoffsetButton.Text = 'zero pipette offset';
            app.channelButtonGroup = uibuttongroup(app.caliTab);
            app.channelButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @channelButtonGroupSelectionChanged, true);
            app.channelButtonGroup.Title = '  channel';
            app.ch1Button = uiradiobutton(app.channelButtonGroup);
            app.ch1Button.Text = 'ch1';
            app.ch1Button.Value = true;
            app.ch2Button = uiradiobutton(app.channelButtonGroup);
            app.ch2Button.Visible = 'off';
            app.ch2Button.Text = 'ch2';
            app.liveButton = uibutton(app.caliTab, 'push');
            app.liveButton.ButtonPushedFcn = createCallbackFcn(app, @liveButtonPushed, true);
            app.liveButton.Text = 'live';
            app.gigasealButton = uibutton(app.caliTab, 'push');
            app.gigasealButton.ButtonPushedFcn = createCallbackFcn(app, @gigasealButtonPushed, true);
            app.gigasealButton.Enable = 'off';
            app.gigasealButton.Text = 'giga seal';
            app.wholecellCheckBox = uicheckbox(app.caliTab);
            app.wholecellCheckBox.Enable = 'off';
            app.wholecellCheckBox.Text = 'whole cell';
            app.GainPanel = uipanel(app.caliTab);
            app.GainPanel.Title = '    Gain';
            app.VCEditFieldLabel = uilabel(app.GainPanel);
            app.VCEditFieldLabel.Text = 'VC/Ω';
            app.VCEditField = uieditfield(app.GainPanel, 'text');
            app.VCEditField.Value = '500M';
            app.CCLabel = uilabel(app.GainPanel);
            app.CCLabel.Text = 'CC/Ω';
            app.CCEditField = uieditfield(app.GainPanel, 'text');
            app.CCEditField.Value = '500M';
            app.VCcmdmVVEditFieldLabel = uilabel(app.GainPanel);
            app.VCcmdmVVEditFieldLabel.Text = 'VC cmd mV/V';
            app.VCcmdmVVEditField = uieditfield(app.GainPanel, 'numeric');
            app.VCcmdmVVEditField.Value = 20;
            app.CCcmdpAVEditFieldLabel = uilabel(app.GainPanel);
            app.CCcmdpAVEditFieldLabel.Text = 'CC cmd pA/V';
            app.CCcmdpAVEditField = uieditfield(app.GainPanel, 'numeric');
            app.CCcmdpAVEditField.Value = 400;
            app.RMEditField_3Label = uilabel(app.caliTab);
            app.RMEditField_3Label.Text = 'R/MΩ';
            app.RMEditField_3 = uieditfield(app.caliTab, 'numeric');
            app.RMEditField_4Label = uilabel(app.caliTab);
            app.RMEditField_4Label.Text = 'R/MΩ';
            app.RMEditField_4 = uieditfield(app.caliTab, 'numeric');
            app.CpFEditField_2Label = uilabel(app.caliTab);
            app.CpFEditField_2Label.Text = 'C/pF';
            app.CpFEditField_2 = uieditfield(app.caliTab, 'numeric');
            app.motionTab = uitab(app.TabGroup);
            app.motionTab.Title = 'motion';
            app.TabGroup4 = uitabgroup(app.motionTab);
            app.xystagesTab = uitab(app.TabGroup4);
            app.xystagesTab.Title = 'xy stages';
            app.UIAxes4 = uiaxes(app.xystagesTab);
            xlabel(app.UIAxes4, 'mm')
            ylabel(app.UIAxes4, 'mm')
            app.UIAxes4.XLim = [-9 9];
            app.UIAxes4.YLim = [-9 9];
            app.UIAxes4.XTick = [-9 -6 -3 0 3 6 9];
            app.UIAxes4.XTickLabel = {'-9'; '-6'; '-3'; '0'; '3'; '6'; '9'};
            app.UIAxes4.YTick = [-9 -6 -3 0 3 6 9];
            app.UIAxes4.YTickLabel = {'-9'; '-6'; '-3'; '0'; '3'; '6'; '9'};
            app.registercurrentposButton = uibutton(app.xystagesTab, 'push');
            app.registercurrentposButton.ButtonPushedFcn = createCallbackFcn(app, @registercurrentposButtonPushed, true);
            app.registercurrentposButton.Text = 'register current pos';
            app.gotoselectedposButton = uibutton(app.xystagesTab, 'push');
            app.gotoselectedposButton.ButtonPushedFcn = createCallbackFcn(app, @gotoselectedposButtonPushed, true);
            app.gotoselectedposButton.Text = 'goto selected pos';
            app.sethomeButton = uibutton(app.xystagesTab, 'push');
            app.sethomeButton.ButtonPushedFcn = createCallbackFcn(app, @sethomeButtonPushed, true);
            app.sethomeButton.Text = 'set home';
            app.gotohomeButton = uibutton(app.xystagesTab, 'push');
            app.gotohomeButton.ButtonPushedFcn = createCallbackFcn(app, @gotohomeButtonPushed, true);
            app.gotohomeButton.Text = 'goto home';
            app.deleteselectedposButton = uibutton(app.xystagesTab, 'push');
            app.deleteselectedposButton.ButtonPushedFcn = createCallbackFcn(app, @deleteselectedposButtonPushed, true);
            app.deleteselectedposButton.Text = 'delete selected pos';
            app.selectposDropDownLabel = uilabel(app.xystagesTab);
            app.selectposDropDownLabel.Text = 'select pos';
            app.selectposDropDown = uidropdown(app.xystagesTab);
            app.selectposDropDown.Items = {};
            app.selectposDropDown.ValueChangedFcn = createCallbackFcn(app, @selectposDropDownValueChanged, true);
            app.selectposDropDown.Value = {};
            app.xSpinnerLabel = uilabel(app.xystagesTab);
            app.xSpinnerLabel.Text = 'x';
            app.xSpinner = uispinner(app.xystagesTab);
            app.xSpinner.Step = 2;
            app.xSpinner.ValueChangedFcn = createCallbackFcn(app, @xSpinnerValueChanged, true);
            app.ySpinnerLabel = uilabel(app.xystagesTab);
            app.ySpinnerLabel.Text = 'y';
            app.ySpinner = uispinner(app.xystagesTab);
            app.ySpinner.Step = 2;
            app.ySpinner.ValueChangedFcn = createCallbackFcn(app, @ySpinnerValueChanged, true);
            app.positionmLabel = uilabel(app.xystagesTab);
            app.positionmLabel.Text = 'position (µm)';
            app.stepsizemLabel = uilabel(app.xystagesTab);
            app.stepsizemLabel.Text = 'step size (µm)';
            app.EditField = uieditfield(app.xystagesTab, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = 2;
            app.EditField_2 = uieditfield(app.xystagesTab, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.Value = 2;
            app.followButton = uibutton(app.xystagesTab, 'push');
            app.followButton.ButtonPushedFcn = createCallbackFcn(app, @followButtonPushed, true);
            app.followButton.Text = 'follow';
            app.zstageTab = uitab(app.TabGroup4);
            app.zstageTab.Title = 'z stage';
            app.manipulatorsTab = uitab(app.TabGroup4);
            app.manipulatorsTab.Title = 'manipulators';
            app.setworkposButton = uibutton(app.manipulatorsTab, 'push');
            app.setworkposButton.ButtonPushedFcn = createCallbackFcn(app, @setworkposButtonPushed, true);
            app.setworkposButton.Text = 'set work pos';
            app.homeButton = uibutton(app.manipulatorsTab, 'push');
            app.homeButton.ButtonPushedFcn = createCallbackFcn(app, @homeButtonPushed, true);
            app.homeButton.Text = 'home';
            app.workButton = uibutton(app.manipulatorsTab, 'push');
            app.workButton.ButtonPushedFcn = createCallbackFcn(app, @workButtonPushed, true);
            app.workButton.Text = 'work';
            app.manipulator1Label = uilabel(app.manipulatorsTab);
            app.manipulator1Label.Text = 'manipulator1';
            app.excitationTab = uitab(app.TabGroup);
            app.excitationTab.Title = 'excitation';
            app.Lamp = uilamp(app.excitationTab);
            app.LEDCheckBox = uicheckbox(app.excitationTab);
            app.LEDCheckBox.ValueChangedFcn = createCallbackFcn(app, @LEDCheckBoxValueChanged, true);
            app.LEDCheckBox.Text = 'LED';
            app.nmlaserCheckBox = uicheckbox(app.excitationTab);
            app.nmlaserCheckBox.ValueChangedFcn = createCallbackFcn(app, @nmlaserCheckBoxValueChanged, true);
            app.nmlaserCheckBox.Text = '473 nm laser';
            app.Lamp2 = uilamp(app.excitationTab);
            app.Slider = uislider(app.excitationTab);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.MinorTicks = [];
            app.Slider.Enable = 'off';
            app.Slider2 = uislider(app.excitationTab);
            app.Slider2.ValueChangingFcn = createCallbackFcn(app, @Slider2ValueChanging, true);
            app.Slider2.MinorTicks = [];
            app.Slider2.Enable = 'off';
            app.powerSpinnerLabel = uilabel(app.excitationTab);
            app.powerSpinnerLabel.Enable = 'off';
            app.powerSpinnerLabel.Text = 'power (%)';
            app.powerSpinner = uispinner(app.excitationTab);
            app.powerSpinner.Limits = [0 100];
            app.powerSpinner.ValueChangedFcn = createCallbackFcn(app, @powerSpinnerValueChanged, true);
            app.powerSpinner.Enable = 'off';
            app.powerSpinner_2Label = uilabel(app.excitationTab);
            app.powerSpinner_2Label.Enable = 'off';
            app.powerSpinner_2Label.Text = 'power (%)';
            app.powerSpinner_2 = uispinner(app.excitationTab);
            app.powerSpinner_2.Limits = [0 100];
            app.powerSpinner_2.ValueChangedFcn = createCallbackFcn(app, @powerSpinner_2ValueChanged, true);
            app.powerSpinner_2.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Dev_ctrl

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end