classdef DAQ_vis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        UIAxes2   matlab.ui.control.UIAxes
        UIAxes    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        callingapp; % 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Dev_ctrl, queued_seg)
            app.callingapp = Dev_ctrl;
            time_trace = length(queued_seg(:,1))/Dev_ctrl.NI_daq.daq_cam.Rate;
            if app.callingapp.deployButton_2.UserData.ch == 1
                app.UIAxes.Visible = "on";
                ao0 = queued_seg(:,1);
                xlim(app.UIAxes,[0 time_trace]);
                ylim(app.UIAxes,[min(ao0) max(ao0)]);
                hold(app.UIAxes, "on");
            elseif app.callingapp.deployButton_2.UserData.ch == 2
                app.UIAxes2.Visible = "on";
                ao0 = queued_seg(:,1);
                xlim(app.UIAxes2,[0 time_trace]);
                ylim(app.UIAxes2,[min(ao0) max(ao0)]);
                hold(app.UIAxes2,"on");
            elseif size(queued_seg,2)>2
                app.UIAxes.Visible = "on";
                ao0 = queued_seg(:,1);
                xlim(app.UIAxes,[0 time_trace]);
                ylim(app.UIAxes,[min(ao0) max(ao0)]);
                hold(app.UIAxes, "on");
                app.UIAxes2.Visible = "on";
                ao1 = queued_seg(:,2);
                xlim(app.UIAxes2,[0 time_trace]);
                ylim(app.UIAxes2,[min(ao1) max(ao1)]);
                hold(app.UIAxes2,"on");
            else
                disp('Please deploy channels.')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'ch1')
            xlabel(app.UIAxes, 'Time / s')
            ylabel(app.UIAxes, 'Voltage / v')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'ch2')
            xlabel(app.UIAxes2, 'Time / s')
            ylabel(app.UIAxes2, 'Voltage / v')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DAQ_vis(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end